classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        SubmitButton                  matlab.ui.control.Button
        DoublecladdingPanel           matlab.ui.container.Panel
        feedbacktextTextArea          matlab.ui.control.TextArea
        feedbacktextTextAreaLabel     matlab.ui.control.Label
        mLabel                        matlab.ui.control.Label
        singlecladdingPanel           matlab.ui.container.Panel
        mWLabel_2                     matlab.ui.control.Label
        signalpowerEditField          matlab.ui.control.NumericEditField
        signalpowerEditFieldLabel     matlab.ui.control.Label
        mWLabel                       matlab.ui.control.Label
        pumppowerEditField            matlab.ui.control.NumericEditField
        pumppowerEditFieldLabel       matlab.ui.control.Label
        isolatorlossEditField         matlab.ui.control.NumericEditField
        isolatorlossEditFieldLabel    matlab.ui.control.Label
        wdmlossEditField              matlab.ui.control.NumericEditField
        wdmlossEditFieldLabel         matlab.ui.control.Label
        taufEditField                 matlab.ui.control.NumericEditField
        taufLabel                     matlab.ui.control.Label
        stepEditField                 matlab.ui.control.NumericEditField
        stepEditFieldLabel            matlab.ui.control.Label
        endEditField                  matlab.ui.control.NumericEditField
        endEditFieldLabel             matlab.ui.control.Label
        signalwavelengthLabel         matlab.ui.control.Label
        startEditField                matlab.ui.control.NumericEditField
        startEditFieldLabel           matlab.ui.control.Label
        pumpwavelengthEditField       matlab.ui.control.NumericEditField
        pumpwavelengthEditFieldLabel  matlab.ui.control.Label
        claddingtypeButtonGroup       matlab.ui.container.ButtonGroup
        doubleButton                  matlab.ui.control.ToggleButton
        singleButton                  matlab.ui.control.ToggleButton
        alphaEditField                matlab.ui.control.NumericEditField
        alphaEditFieldLabel           matlab.ui.control.Label
        neffEditField                 matlab.ui.control.NumericEditField
        neffEditFieldLabel            matlab.ui.control.Label
        N0EditField                   matlab.ui.control.NumericEditField
        N0EditFieldLabel              matlab.ui.control.Label
        SteadystateAmplifierLabel     matlab.ui.control.Label
        LEditField                    matlab.ui.control.NumericEditField
        LEditFieldLabel               matlab.ui.control.Label
    end

    
    properties (Access = private)
        feedback % used to check outputs

        L     
        N0
        neff
        alpha


    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.DoublecladdingPanel.Visible = "off";
        end

        % Button down function: claddingtypeButtonGroup
        function claddingtypeButtonGroupButtonDown(app, event)
              val = app.singleButton.Value;
              app.doubleButton.Value = true;
        end

        % Value changed function: feedbacktextTextArea
        function feedbacktextTextAreaValueChanged(app, event)
            value = app.feedbacktextTextArea.Value;
            
        end

        % Callback function: feedbacktextTextArea
        function feedbacktextTextAreaValueChanging(app, event)
            changingValue = event.Value;
            if app.feedback == 1
                app.feedbacktextTextArea.Value = "morning bitch !";
            else 
                app.feedbacktextTextArea.Value = "its not changing";
            end

        end

        % Selection changed function: claddingtypeButtonGroup
        function claddingtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.claddingtypeButtonGroup.SelectedObject;
            if app.singleButton.Value == 1
                app.DoublecladdingPanel.Visible = "off";
                app.singlecladdingPanel.Visible = "on";
            else
                app.singlecladdingPanel.Visible = "off";
                app.DoublecladdingPanel.Visible = "on";
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            run("EDF_Amplifier_0.m");
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L = ';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            app.SteadystateAmplifierLabel = uilabel(app.UIFigure);
            app.SteadystateAmplifierLabel.Text = 'Steady-state Amplifier';
            app.N0EditFieldLabel = uilabel(app.UIFigure);
            app.N0EditFieldLabel.Text = 'N0 =';
            app.N0EditField = uieditfield(app.UIFigure, 'numeric');
            app.neffEditFieldLabel = uilabel(app.UIFigure);
            app.neffEditFieldLabel.Text = 'neff =';
            app.neffEditField = uieditfield(app.UIFigure, 'numeric');
            app.alphaEditFieldLabel = uilabel(app.UIFigure);
            app.alphaEditFieldLabel.Text = 'alpha =';
            app.alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.claddingtypeButtonGroup = uibuttongroup(app.UIFigure);
            app.claddingtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @claddingtypeButtonGroupSelectionChanged, true);
            app.claddingtypeButtonGroup.Title = 'cladding type ';
            app.claddingtypeButtonGroup.ButtonDownFcn = createCallbackFcn(app, @claddingtypeButtonGroupButtonDown, true);
            app.singleButton = uitogglebutton(app.claddingtypeButtonGroup);
            app.singleButton.Text = 'single';
            app.singleButton.Value = true;
            app.doubleButton = uitogglebutton(app.claddingtypeButtonGroup);
            app.doubleButton.Text = 'double';
            app.singlecladdingPanel = uipanel(app.UIFigure);
            app.singlecladdingPanel.Title = 'single cladding';
            app.pumpwavelengthEditFieldLabel = uilabel(app.singlecladdingPanel);
            app.pumpwavelengthEditFieldLabel.Text = 'pump wavelength =';
            app.pumpwavelengthEditField = uieditfield(app.singlecladdingPanel, 'numeric');
            app.startEditFieldLabel = uilabel(app.singlecladdingPanel);
            app.startEditFieldLabel.Text = 'start =';
            app.startEditField = uieditfield(app.singlecladdingPanel, 'numeric');
            app.signalwavelengthLabel = uilabel(app.singlecladdingPanel);
            app.signalwavelengthLabel.Text = 'signal wavelength : ';
            app.endEditFieldLabel = uilabel(app.singlecladdingPanel);
            app.endEditFieldLabel.Text = 'end =';
            app.endEditField = uieditfield(app.singlecladdingPanel, 'numeric');
            app.stepEditFieldLabel = uilabel(app.singlecladdingPanel);
            app.stepEditFieldLabel.Text = 'step =';
            app.stepEditField = uieditfield(app.singlecladdingPanel, 'numeric');
            app.taufLabel = uilabel(app.singlecladdingPanel);
            app.taufLabel.Text = 'tauf  =';
            app.taufEditField = uieditfield(app.singlecladdingPanel, 'numeric');
            app.wdmlossEditFieldLabel = uilabel(app.singlecladdingPanel);
            app.wdmlossEditFieldLabel.Text = 'wdm loss =';
            app.wdmlossEditField = uieditfield(app.singlecladdingPanel, 'numeric');
            app.isolatorlossEditFieldLabel = uilabel(app.singlecladdingPanel);
            app.isolatorlossEditFieldLabel.Text = 'isolator loss =';
            app.isolatorlossEditField = uieditfield(app.singlecladdingPanel, 'numeric');
            app.pumppowerEditFieldLabel = uilabel(app.singlecladdingPanel);
            app.pumppowerEditFieldLabel.Text = 'pump power =';
            app.pumppowerEditField = uieditfield(app.singlecladdingPanel, 'numeric');
            app.mWLabel = uilabel(app.singlecladdingPanel);
            app.mWLabel.Text = 'mW';
            app.signalpowerEditFieldLabel = uilabel(app.singlecladdingPanel);
            app.signalpowerEditFieldLabel.Text = 'signal power =';
            app.signalpowerEditField = uieditfield(app.singlecladdingPanel, 'numeric');
            app.mWLabel_2 = uilabel(app.singlecladdingPanel);
            app.mWLabel_2.Text = 'mW';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.feedbacktextTextAreaLabel = uilabel(app.UIFigure);
            app.feedbacktextTextAreaLabel.Text = 'feedback text';
            app.feedbacktextTextArea = uitextarea(app.UIFigure);
            app.feedbacktextTextArea.ValueChangedFcn = createCallbackFcn(app, @feedbacktextTextAreaValueChanged, true);
            app.feedbacktextTextArea.ValueChangingFcn = createCallbackFcn(app, @feedbacktextTextAreaValueChanging, true);
            app.DoublecladdingPanel = uipanel(app.UIFigure);
            app.DoublecladdingPanel.Title = 'Double cladding';
            app.SubmitButton = uibutton(app.UIFigure, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Text = 'Submit';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end