classdef demo_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        taufEditField                   matlab.ui.control.NumericEditField
        taufEditFieldLabel              matlab.ui.control.Label
        mLabel_3                        matlab.ui.control.Label
        mLabel_2                        matlab.ui.control.Label
        PlotButton                      matlab.ui.control.Button
        YaxisDropDown                   matlab.ui.control.DropDown
        YaxisDropDownLabel              matlab.ui.control.Label
        PLOTLabel                       matlab.ui.control.Label
        XaxisDropDown                   matlab.ui.control.DropDown
        XaxisDropDownLabel              matlab.ui.control.Label
        mWLabel_2                       matlab.ui.control.Label
        signalpowerEditField            matlab.ui.control.NumericEditField
        signalpowerEditFieldLabel       matlab.ui.control.Label
        mWLabel                         matlab.ui.control.Label
        pumppowerEditField              matlab.ui.control.NumericEditField
        pumppowerEditFieldLabel         matlab.ui.control.Label
        mLabel_4                        matlab.ui.control.Label
        mLabel                          matlab.ui.control.Label
        nmLabel_4                       matlab.ui.control.Label
        additionalEditField             matlab.ui.control.NumericEditField
        additionalEditFieldLabel        matlab.ui.control.Label
        aomEditField                    matlab.ui.control.NumericEditField
        aomEditFieldLabel               matlab.ui.control.Label
        coupleratoutputportEditField_2  matlab.ui.control.NumericEditField
        coupleratoutputportEditField_2Label  matlab.ui.control.Label
        coupleratfeedbackportEditField  matlab.ui.control.NumericEditField
        coupleratoutputportLabel        matlab.ui.control.Label
        isolatorEditField               matlab.ui.control.NumericEditField
        isolatorEditFieldLabel          matlab.ui.control.Label
        wdmEditField                    matlab.ui.control.NumericEditField
        wdmEditFieldLabel               matlab.ui.control.Label
        TypeButtonGroup                 matlab.ui.container.ButtonGroup
        SteadystateAmplifierButton      matlab.ui.control.RadioButton
        LossesLabel                     matlab.ui.control.Label
        nmLabel_3                       matlab.ui.control.Label
        nmLabel_2                       matlab.ui.control.Label
        nmLabel                         matlab.ui.control.Label
        stepEditField                   matlab.ui.control.NumericEditField
        stepEditFieldLabel              matlab.ui.control.Label
        endEditField                    matlab.ui.control.NumericEditField
        endEditFieldLabel               matlab.ui.control.Label
        startEditField                  matlab.ui.control.NumericEditField
        startEditFieldLabel             matlab.ui.control.Label
        SignalwavelengthLabel           matlab.ui.control.Label
        pumpwavelengthEditField         matlab.ui.control.NumericEditField
        pumpwavelengthEditFieldLabel    matlab.ui.control.Label
        rpEditField                     matlab.ui.control.NumericEditField
        rpEditFieldLabel                matlab.ui.control.Label
        rsEditField                     matlab.ui.control.NumericEditField
        rsEditFieldLabel                matlab.ui.control.Label
        NApEditField                    matlab.ui.control.NumericEditField
        NApEditFieldLabel               matlab.ui.control.Label
        NAsEditField                    matlab.ui.control.NumericEditField
        NAsEditFieldLabel               matlab.ui.control.Label
        CladdingtypeButtonGroup         matlab.ui.container.ButtonGroup
        DoubleButton                    matlab.ui.control.ToggleButton
        SingleButton                    matlab.ui.control.ToggleButton
        alphaEditField                  matlab.ui.control.NumericEditField
        alphaEditFieldLabel             matlab.ui.control.Label
        neffEditField                   matlab.ui.control.NumericEditField
        neffEditFieldLabel              matlab.ui.control.Label
        N0EditField                     matlab.ui.control.NumericEditField
        N0EditFieldLabel                matlab.ui.control.Label
        LEditField                      matlab.ui.control.NumericEditField
        LEditFieldLabel                 matlab.ui.control.Label
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        L
        N0
        NAs
        NAp
        neff
        rs
        rp
        alpha
        lambda_p
        lambda_s_start
        lambda_s_end
        lambda_s_step
        tauf
        wdm_l
        iso_l
        Pp
        Ps
        type_of_cladding
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           
            app.NApEditField.Enable = 0;
            app.rpEditField.Enable = 0;
            app.aomEditField.Enable = 0;
            app.additionalEditField.Enable = 0;
            app.coupleratfeedbackportEditField.Enable = 0;
            app.coupleratoutputportEditField_2.Enable = 0;
            
        end

        % Selection changed function: CladdingtypeButtonGroup
        function CladdingtypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.CladdingtypeButtonGroup.SelectedObject;
            if app.SingleButton.Value == 1
                app.type_of_cladding = "single";
                app.NApEditField.Value = 0;
                app.rpEditField.Value =0;
                app.NApEditField.Enable = 0;
                app.rpEditField.Enable = 0;
            else
                app.type_of_cladding = "double";
                app.NApEditField.Enable = 1;
                app.rpEditField.Enable = 1;
            end
        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            app.L = app.LEditField.Value;
            app.N0 = app.N0EditField.Value;
            app.NAs = app.NAsEditField.Value;
            app.neff = app.neffEditField.Value;
            app.rs = app.rsEditField.Value;
            app.alpha = app.alphaEditField.Value;
            app.lambda_p = app.pumpwavelengthEditField.Value;
            app.lambda_s_start = app.startEditField.Value;
            app.lambda_s_end = app.endEditField.Value;
            app.lambda_s_step = app.stepEditField.Value;
            app.tauf = app.taufEditField.Value;
            app.wdm_l = app.wdmEditField.Value;
            app.iso_l = app.isolatorEditField.Value;
            app.Pp = app.pumppowerEditField.Value;
            app.Ps = app.signalpowerEditField.Value;

            EDF_Amplifier_0(app.L,app.N0,app.NAs,app.neff,app.rs,app.alpha,app.lambda_p,app.lambda_s_start,app.lambda_s_end,app.lambda_s_step,app.tauf,app.wdm_l,app.iso_l,app.Pp,app.Ps);

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.LEditFieldLabel = uilabel(app.UIFigure);
            app.LEditFieldLabel.Text = 'L =';
            app.LEditField = uieditfield(app.UIFigure, 'numeric');
            app.N0EditFieldLabel = uilabel(app.UIFigure);
            app.N0EditFieldLabel.Text = 'N0 =';
            app.N0EditField = uieditfield(app.UIFigure, 'numeric');
            app.neffEditFieldLabel = uilabel(app.UIFigure);
            app.neffEditFieldLabel.Text = 'neff =';
            app.neffEditField = uieditfield(app.UIFigure, 'numeric');
            app.alphaEditFieldLabel = uilabel(app.UIFigure);
            app.alphaEditFieldLabel.Text = 'alpha =';
            app.alphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.CladdingtypeButtonGroup = uibuttongroup(app.UIFigure);
            app.CladdingtypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @CladdingtypeButtonGroupSelectionChanged, true);
            app.CladdingtypeButtonGroup.Title = 'Cladding type :';
            app.SingleButton = uitogglebutton(app.CladdingtypeButtonGroup);
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.DoubleButton = uitogglebutton(app.CladdingtypeButtonGroup);
            app.DoubleButton.Text = 'Double';
            app.NAsEditFieldLabel = uilabel(app.UIFigure);
            app.NAsEditFieldLabel.Text = 'NAs =';
            app.NAsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NApEditFieldLabel = uilabel(app.UIFigure);
            app.NApEditFieldLabel.Text = 'NAp =';
            app.NApEditField = uieditfield(app.UIFigure, 'numeric');
            app.rsEditFieldLabel = uilabel(app.UIFigure);
            app.rsEditFieldLabel.Text = 'rs =';
            app.rsEditField = uieditfield(app.UIFigure, 'numeric');
            app.rpEditFieldLabel = uilabel(app.UIFigure);
            app.rpEditFieldLabel.Text = 'rp =';
            app.rpEditField = uieditfield(app.UIFigure, 'numeric');
            app.pumpwavelengthEditFieldLabel = uilabel(app.UIFigure);
            app.pumpwavelengthEditFieldLabel.Text = 'pump wavelength =';
            app.pumpwavelengthEditField = uieditfield(app.UIFigure, 'numeric');
            app.SignalwavelengthLabel = uilabel(app.UIFigure);
            app.SignalwavelengthLabel.Text = 'Signal wavelength :';
            app.startEditFieldLabel = uilabel(app.UIFigure);
            app.startEditFieldLabel.Text = 'start =';
            app.startEditField = uieditfield(app.UIFigure, 'numeric');
            app.endEditFieldLabel = uilabel(app.UIFigure);
            app.endEditFieldLabel.Text = 'end =';
            app.endEditField = uieditfield(app.UIFigure, 'numeric');
            app.stepEditFieldLabel = uilabel(app.UIFigure);
            app.stepEditFieldLabel.Text = 'step =';
            app.stepEditField = uieditfield(app.UIFigure, 'numeric');
            app.nmLabel = uilabel(app.UIFigure);
            app.nmLabel.Text = 'nm';
            app.nmLabel_2 = uilabel(app.UIFigure);
            app.nmLabel_2.Text = 'nm';
            app.nmLabel_3 = uilabel(app.UIFigure);
            app.nmLabel_3.Text = 'nm';
            app.LossesLabel = uilabel(app.UIFigure);
            app.LossesLabel.Text = 'Losses :';
            app.TypeButtonGroup = uibuttongroup(app.UIFigure);
            app.TypeButtonGroup.Title = 'Type :';
            app.SteadystateAmplifierButton = uiradiobutton(app.TypeButtonGroup);
            app.SteadystateAmplifierButton.Text = 'Steady state Amplifier';
            app.SteadystateAmplifierButton.Value = true;
            app.wdmEditFieldLabel = uilabel(app.UIFigure);
            app.wdmEditFieldLabel.Text = 'wdm =';
            app.wdmEditField = uieditfield(app.UIFigure, 'numeric');
            app.isolatorEditFieldLabel = uilabel(app.UIFigure);
            app.isolatorEditFieldLabel.Text = 'isolator =';
            app.isolatorEditField = uieditfield(app.UIFigure, 'numeric');
            app.coupleratoutputportLabel = uilabel(app.UIFigure);
            app.coupleratoutputportLabel.Text = 'coupler at feedback port =';
            app.coupleratfeedbackportEditField = uieditfield(app.UIFigure, 'numeric');
            app.coupleratoutputportEditField_2Label = uilabel(app.UIFigure);
            app.coupleratoutputportEditField_2Label.Text = 'coupler at output port =';
            app.coupleratoutputportEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.aomEditFieldLabel = uilabel(app.UIFigure);
            app.aomEditFieldLabel.Text = 'aom =';
            app.aomEditField = uieditfield(app.UIFigure, 'numeric');
            app.additionalEditFieldLabel = uilabel(app.UIFigure);
            app.additionalEditFieldLabel.Text = 'additional =';
            app.additionalEditField = uieditfield(app.UIFigure, 'numeric');
            app.nmLabel_4 = uilabel(app.UIFigure);
            app.nmLabel_4.Text = 'nm';
            app.mLabel = uilabel(app.UIFigure);
            app.mLabel.Text = 'm';
            app.mLabel_4 = uilabel(app.UIFigure);
            app.mLabel_4.Text = 'm';
            app.pumppowerEditFieldLabel = uilabel(app.UIFigure);
            app.pumppowerEditFieldLabel.Text = 'pump power =';
            app.pumppowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.mWLabel = uilabel(app.UIFigure);
            app.mWLabel.Text = 'mW';
            app.signalpowerEditFieldLabel = uilabel(app.UIFigure);
            app.signalpowerEditFieldLabel.Text = 'signal power =';
            app.signalpowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.mWLabel_2 = uilabel(app.UIFigure);
            app.mWLabel_2.Text = 'mW';
            app.XaxisDropDownLabel = uilabel(app.UIFigure);
            app.XaxisDropDownLabel.Text = 'X axis : ';
            app.XaxisDropDown = uidropdown(app.UIFigure);
            app.PLOTLabel = uilabel(app.UIFigure);
            app.PLOTLabel.Text = 'PLOT :';
            app.YaxisDropDownLabel = uilabel(app.UIFigure);
            app.YaxisDropDownLabel.Text = 'Y axis :';
            app.YaxisDropDown = uidropdown(app.UIFigure);
            app.PlotButton = uibutton(app.UIFigure, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.mLabel_2 = uilabel(app.UIFigure);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.UIFigure);
            app.mLabel_3.Text = '/m';
            app.taufEditFieldLabel = uilabel(app.UIFigure);
            app.taufEditFieldLabel.Text = 'tauf =';
            app.taufEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = demo_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end