classdef project_app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        Tab1                           matlab.ui.container.Tab
        GainmediumButtonGroup          matlab.ui.container.ButtonGroup
        FilepathEditField              matlab.ui.control.EditField
        FilepathEditFieldLabel         matlab.ui.control.Label
        CustomFiberButton              matlab.ui.control.RadioButton
        ThuliumDopedFiberButton        matlab.ui.control.RadioButton
        YiterbiumDopedFiberButton      matlab.ui.control.RadioButton
        ErbiumDopedFiberButton         matlab.ui.control.RadioButton
        TypeButtonGroup                matlab.ui.container.ButtonGroup
        PulsedLaserSimulationButton    matlab.ui.control.RadioButton
        FDTDLaserSimulationButton      matlab.ui.control.RadioButton
        SteadyStateLaserSimulationButton  matlab.ui.control.RadioButton
        SteadyStateAmplifierSimulationButton  matlab.ui.control.RadioButton
        Tab2                           matlab.ui.container.Tab
        m3Label                        matlab.ui.control.Label
        sLabel                         matlab.ui.control.Label
        mLabel_3                       matlab.ui.control.Label
        mLabel_2                       matlab.ui.control.Label
        DbLabel                        matlab.ui.control.Label
        mLabel                         matlab.ui.control.Label
        taufEditField                  matlab.ui.control.NumericEditField
        LifetimeofMetastableStatetau_fLabel  matlab.ui.control.Label
        rpEditField                    matlab.ui.control.NumericEditField
        RadiusofCladdingrsubpsubLabel  matlab.ui.control.Label
        rsEditField                    matlab.ui.control.NumericEditField
        RadiusofCorersubssubLabel      matlab.ui.control.Label
        NApEditField                   matlab.ui.control.NumericEditField
        NumericalAppertureofCladdingNAsubpsubLabel  matlab.ui.control.Label
        NAsEditField                   matlab.ui.control.NumericEditField
        NA_sLabel                      matlab.ui.control.Label
        TypeofCladdingButtonGroup      matlab.ui.container.ButtonGroup
        DoubleButton                   matlab.ui.control.RadioButton
        SingleButton                   matlab.ui.control.RadioButton
        alphaEditField                 matlab.ui.control.NumericEditField
        alphaLabel                     matlab.ui.control.Label
        neffEditField                  matlab.ui.control.NumericEditField
        nsubeffsubLabel                matlab.ui.control.Label
        N0EditField                    matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        LengthofFiberLEditField        matlab.ui.control.NumericEditField
        LengthofFiberLLabel            matlab.ui.control.Label
        FiberParametersLabel           matlab.ui.control.Label
        Tab7                           matlab.ui.container.Tab
        K1EditField                    matlab.ui.control.NumericEditField
        CrossRelaxationCoefficientLabel  matlab.ui.control.Label
        p956mpLabel_2                  matlab.ui.control.Label
        tau32EditField                 matlab.ui.control.NumericEditField
        tau32Label                     matlab.ui.control.Label
        p956mpLabel                    matlab.ui.control.Label
        tau21EditField                 matlab.ui.control.NumericEditField
        tau21Label                     matlab.ui.control.Label
        MoreFiberParametersLabel       matlab.ui.control.Label
        Tab3                           matlab.ui.container.Tab
        mLabel_7                       matlab.ui.control.Label
        CavityLengthEditField          matlab.ui.control.NumericEditField
        CavityLengthEditFieldLabel     matlab.ui.control.Label
        dBLabel_7                      matlab.ui.control.Label
        dBLabel_6                      matlab.ui.control.Label
        dBLabel_5                      matlab.ui.control.Label
        dBLabel_4                      matlab.ui.control.Label
        dBLabel_3                      matlab.ui.control.Label
        dBLabel_2                      matlab.ui.control.Label
        dBLabel                        matlab.ui.control.Label
        c_lEditField                   matlab.ui.control.NumericEditField
        additionalconnectorlossEditFieldLabel  matlab.ui.control.Label
        aom_lEditField                 matlab.ui.control.NumericEditField
        insertionlossofaomLabel        matlab.ui.control.Label
        wdm_lEditField                 matlab.ui.control.NumericEditField
        insertionlossofwdmEditFieldLabel  matlab.ui.control.Label
        c_outEditField                 matlab.ui.control.NumericEditField
        insertionlossofcoupleratoutputportEditFieldLabel  matlab.ui.control.Label
        cout_lEditField                matlab.ui.control.NumericEditField
        insertionlossofcoupleratfeedbackportEditFieldLabel  matlab.ui.control.Label
        wdm_pEditField                 matlab.ui.control.NumericEditField
        insertionlossofwdmatpumpLabel  matlab.ui.control.Label
        iso_lEditField                 matlab.ui.control.NumericEditField
        insertionlossofisolatorEditFieldLabel  matlab.ui.control.Label
        LossesLabel                    matlab.ui.control.Label
        CavityParametersLabel          matlab.ui.control.Label
        Tab4                           matlab.ui.container.Tab
        mWLabel_4                      matlab.ui.control.Label
        mWLabel_3                      matlab.ui.control.Label
        BackwardEditField              matlab.ui.control.NumericEditField
        BackwardEditFieldLabel         matlab.ui.control.Label
        ForwardEditField               matlab.ui.control.NumericEditField
        ForwardEditFieldLabel          matlab.ui.control.Label
        PumpPowerLabel                 matlab.ui.control.Label
        nmLabel_4                      matlab.ui.control.Label
        mLabel_6                       matlab.ui.control.Label
        mLabel_5                       matlab.ui.control.Label
        mLabel_4                       matlab.ui.control.Label
        mWLabel_2                      matlab.ui.control.Label
        mWLabel                        matlab.ui.control.Label
        SignalPowerEditField           matlab.ui.control.NumericEditField
        SignalPowerEditFieldLabel      matlab.ui.control.Label
        PumpPowerEditField             matlab.ui.control.NumericEditField
        PumpPowerEditFieldLabel        matlab.ui.control.Label
        lambda_s_stepEditField         matlab.ui.control.NumericEditField
        SignalWavelengthlambda_sstepLabel  matlab.ui.control.Label
        lambda_s_endEditField          matlab.ui.control.NumericEditField
        lambda_sendLabel               matlab.ui.control.Label
        lambda_s_startEditField        matlab.ui.control.NumericEditField
        lambda_sstartLabel             matlab.ui.control.Label
        lambda_pEditField              matlab.ui.control.NumericEditField
        lambda_pLabel                  matlab.ui.control.Label
        InputsLabel                    matlab.ui.control.Label
        Tab6                           matlab.ui.container.Tab
        NEditField                     matlab.ui.control.NumericEditField
        pNumberofstepsbroverthegainmediumpLabel  matlab.ui.control.Label
        REditField                     matlab.ui.control.NumericEditField
        StabilityFactorEditFieldLabel  matlab.ui.control.Label
        qEditField                     matlab.ui.control.NumericEditField
        NumberofCyclesEditFieldLabel   matlab.ui.control.Label
        sLabel_2                       matlab.ui.control.Label
        TEditField                     matlab.ui.control.NumericEditField
        CycleTimeLabel                 matlab.ui.control.Label
        FDTDSimulationParametersLabel  matlab.ui.control.Label
        Tab5                           matlab.ui.container.Tab
        RefreshButton                  matlab.ui.control.Button
        PlotButton                     matlab.ui.control.Button
        YaxisDropDown                  matlab.ui.control.DropDown
        YaxisDropDownLabel             matlab.ui.control.Label
        XaxisDropDown                  matlab.ui.control.DropDown
        XaxisDropDownLabel             matlab.ui.control.Label
        OutputsLabel                   matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        graph_X_axis = "Z" % X-axis of graph
        graph_Y_axis = "Pump power" % Y-axis of graph
        Cladding_type = "Single" % Description
        Gain_medium = "Erbium" % Description
        Fiber_filepath = "D:\Deepa madam's project\Erbium codes\Q-switchedLaser_Codes\EDFAmplifier_Laser_SteadyState\HG980.csv" % Description
        Type_of_simulation = "Steady State Amplifier Simulation" % Description

        
        %Steady State Laser RAM
        Z_ssl = []          
        lambda_s_ssl = []
        Spectrum_out_ssl = []
        Gain_out_ssl = []
        Pp_out_ssl = []
        Ps_out_ssl = []
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

           %Initializing

           %Tab-1
           app.SteadyStateLaserSimulationButton.Value = false;
           app.FDTDLaserSimulationButton.Value = false;
           app.PulsedLaserSimulationButton.Value = false;
           app.ThuliumDopedFiberButton.Value = false ;
           app.YiterbiumDopedFiberButton.Value = false ;
           app.CustomFiberButton.Value = false;
           app.FilepathEditField.Enable = 0;

           %Tab-2
           app.LengthofFiberLEditField.Value = 3.5;
           app.N0EditField.Value = 2e25;
           app.neffEditField.Value = 1.5;
           app.alphaEditField.Value = 0;
           app.DoubleButton.Value = false;
           app.NAsEditField.Value = 0.29;
           app.NApEditField.Enable = 0;
           app.rsEditField.Value = 2.2e-6;
           app.rpEditField.Enable = 0;
           app.taufEditField.Value = 10e-3;

           %Tab-3
           app.wdm_pEditField.Value = 1.55;
           app.iso_lEditField.Value = 0.9;
           app.c_lEditField.Enable = 0;
           app.aom_lEditField.Enable = 0;
           app.wdm_lEditField.Enable = 0;
           app.cout_lEditField.Enable = 0;
           app.c_outEditField.Enable = 0;

           %FTDT Special
           app.CavityLengthEditField.Visible = 0;
           app.CavityLengthEditFieldLabel.Visible = 0;
           app.CavityLengthEditField.Value = 0;
           app.mLabel_7.Visible = 0;

           %app.Tab6.Visible = 0;

           %Tab-4
           app.lambda_pEditField.Value = 976;
           app.lambda_s_startEditField.Value = 1501e-9;
           app.lambda_s_endEditField.Value = 1600e-9;
           app.lambda_s_stepEditField.Value = 0.1e-9;
           app.PumpPowerEditField.Value = 20;
           app.mWLabel_2.Text = "mW";
           app.mWLabel.Text = "mW";
           app.SignalPowerEditField.Value = 0;

           %Tab-5
           app.TEditField.Enable = 0;
           app.TEditField.Value = 0;
           app.qEditField.Enable = 0;
           app.qEditField.Value = 0;
           app.REditField.Enable = 0;
           app.REditField.Value = 0;
           app.NEditField.Enable = 0;
           app.NEditField.Value = 0;

           %FTDT Special
           app.PumpPowerLabel.Visible = 0;
           app.ForwardEditField.Visible = 0;
           app.ForwardEditField.Value = 0;
           app.ForwardEditFieldLabel.Visible = 0;
           app.BackwardEditField.Visible = 0;
           app.BackwardEditField.Value = 0;
           app.BackwardEditFieldLabel.Visible = 0;
           app.mWLabel_3.Visible = 0;
           app.mWLabel_4.Visible = 0;
           

           %Tab-5
           app.YaxisDropDown.Items = ["Pump power","Signal power","Excited State Population"];
           app.XaxisDropDown.Items = ["Z","Signal wavelength"];

           %Tab-7
           app.tau21EditField.Enable = 0;
           app.tau32EditField.Enable = 0;
           app.K1EditField.Enable = 0;

        end

        % Button pushed function: PlotButton
        function PlotButtonPushed(app, event)
            
            %app.lambda_s_startEditField.Value = app.lambda_s_startEditField.Value*1e-9;
            %app.lambda_s_endEditField.Value = app.lambda_s_endEditField.Value*1e-9;
            %app.lambda_s_stepEditField.Value = app.lambda_s_stepEditField.Value*1e-9;

            if app.Type_of_simulation == "Steady State Amplifier Simulation"

                app.Z_ssl = [];          
                app.lambda_s_ssl = [];
                app.Spectrum_out_ssl = [];
                app.Gain_out_ssl = [];
                app.Pp_out_ssl = [];
                app.Ps_out_ssl = [];
                
                if app.Gain_medium == "Erbium"
                    [Pp,Z,Spectrum,Ps_out,lambda_s] = EDF_Amplifier_0(app.LengthofFiberLEditField.Value, app.N0EditField.Value, app.NAsEditField.Value, app.neffEditField.Value, app.rsEditField.Value, app.alphaEditField.Value, app.lambda_pEditField.Value, app.lambda_s_startEditField.Value*1e-9, app.lambda_s_endEditField.Value*1e-9, app.lambda_s_stepEditField.Value*1e-9, app.taufEditField.Value, app.wdm_pEditField.Value, app.iso_lEditField.Value, app.PumpPowerEditField.Value*1e-3, app.SignalPowerEditField.Value, app.Fiber_filepath);
                                               
                    if app.graph_X_axis == "Z" && app.graph_Y_axis == "Pump power"
                        app.UIAxes.XLabel.String = "Fiber position (m)";
                        app.UIAxes.YLabel.String = "Pump power (mW)";
                        plot(app.UIAxes,Z,Pp);
        
                    elseif app.graph_X_axis == "Z" && app.graph_Y_axis == "Signal power"
                        app.UIAxes.XLabel.String = "Fiber position (m)";
                        app.UIAxes.YLabel.String = "Signal power (mW)";
                        plot(app.UIAxes,Z,Ps_out);
        
                    elseif app.graph_X_axis == "Z" && app.graph_Y_axis == "Excited State Population"
                        app.UIAxes.XLabel.String = "Fiber position (m)";
                        app.UIAxes.YLabel.String = 'N_1/N_0, N_2/N_0';
                        plot(app.UIAxes,Z,Excited_state_pop);
        
                    elseif app.graph_X_axis == "Signal wavelength" && app.graph_Y_axis == "Spectrum"
                        app.UIAxes.XLabel.String = "Wavelength (m)";
                        app.UIAxes.YLabel.String = "Power (dBm)";
                        plot(app.UIAxes,lambda_s,Spectrum);
                    end 

                elseif app.Gain_medium == "Thulium"

                    [Pp,Z,Spectrum,Ps_out,lambda_s,Excited_state_pop] = TDFA_DCF_0(app.LengthofFiberLEditField.Value,app.N0EditField.Value,app.NAsEditField.Value,app.NApEditField.Value,app.neffEditField.Value,app.rsEditField.Value,app.rpEditField.Value,app.alphaEditField.Value,app.lambda_pEditField.Value,app.tau21EditField.Value*1e-6,app.tau32EditField.Value*1e-6,app.K1EditField.Value,app.PumpPowerEditField.Value, app.SignalPowerEditField.Value,"n");
                                               
                    if app.graph_X_axis == "Z" && app.graph_Y_axis == "Pump power"
                        app.UIAxes.XLabel.String = "Fiber position (m)";
                        app.UIAxes.YLabel.String = "Pump power (mW)";
                        plot(app.UIAxes,Z,Pp);
        
                    elseif app.graph_X_axis == "Z" && app.graph_Y_axis == "Signal power"
                        app.UIAxes.XLabel.String = "Fiber position (m)";
                        app.UIAxes.YLabel.String = "Signal power (mW)";
                        plot(app.UIAxes,Z,Ps_out);
        
                    elseif app.graph_X_axis == "Z" && app.graph_Y_axis == "Excited State Population"
                        app.UIAxes.XLabel.String = "Fiber position (m)";
                        app.UIAxes.YLabel.String = 'N_1/N_0, N_2/N_0';
                        plot(app.UIAxes,Z,Excited_state_pop);
        
                    elseif app.graph_X_axis == "Signal wavelength" && app.graph_Y_axis == "Spectrum"
                        app.UIAxes.XLabel.String = "Wavelength (m)";
                        app.UIAxes.YLabel.String = "Power (dBm)";
                        plot(app.UIAxes,lambda_s,Spectrum);
                    end 
                end

            elseif app.Type_of_simulation == "Steady State Laser Simulation"

                if app.Gain_medium == "Erbium"

                    if isempty(app.Pp_out_ssl) || isempty(app.Z_ssl) || isempty(app.Spectrum_out_ssl) || isempty(app.Ps_out_ssl) || isempty(app.lambda_s_ssl) || isempty(app.Gain_out_ssl)
                        [Z,lambda_s,Spectrum_out,Gain_out,Pp_out,Ps_out] = EDF_CWLaser_0(app.LengthofFiberLEditField.Value, app.N0EditField.Value, app.NAsEditField.Value, app.neffEditField.Value, app.rsEditField.Value, app.alphaEditField.Value, app.lambda_pEditField.Value, app.lambda_s_startEditField.Value, app.lambda_s_endEditField.Value, app.lambda_s_stepEditField.Value, app.taufEditField.Value, app.wdm_pEditField.Value, app.iso_lEditField.Value,app.cout_lEditField.Value, app.c_outEditField.Value, app.wdm_lEditField.Value, app.aom_lEditField.Value, app.c_lEditField.Value, app.PumpPowerEditField.Value, app.Fiber_filepath);
                        
                        app.Pp_out_ssl = Pp_out;
                        app.Z_ssl = Z;
                        app.Spectrum_out_ssl = Spectrum_out;
                        app.Ps_out_ssl = Ps_out;
                        app.lambda_s_ssl = lambda_s;
                        app.Gain_out_ssl = Gain_out;
                    end
    
                    if app.graph_X_axis == "Z" && app.graph_Y_axis == "Pump power"
                        app.UIAxes.XLabel.String = "Fiber position (m)";
                        app.UIAxes.YLabel.String = "Pump power (mW)";
                        plot(app.UIAxes,app.Z_ssl,app.Pp_out_ssl);
        
                    elseif app.graph_X_axis == "Z" && app.graph_Y_axis == "Signal power"
                        app.UIAxes.XLabel.String = "Fiber position (m)";
                        app.UIAxes.YLabel.String = "Signal power (mW)";
                        plot(app.UIAxes,app.Z_ssl,app.Ps_out_ssl);
        
                    elseif app.graph_X_axis == "Signal wavelength" && app.graph_Y_axis == "Gain"
                        app.UIAxes.XLabel.String = "Wavelength (m)";
                        app.UIAxes.YLabel.String = 'Gain (dB)';
                        plot(app.UIAxes,app.lambda_s_ssl,app.Gain_out_ssl);
        
                    elseif app.graph_X_axis == "Signal wavelength" && app.graph_Y_axis == "Spectrum"
                        app.UIAxes.XLabel.String = "Wavelength (m)";
                        app.UIAxes.YLabel.String = "Power (dBm)";
                        plot(app.UIAxes,app.lambda_s_ssl,app.Spectrum_out_ssl);
                    end 
                
                elseif app.Gain_medium == "Thulium"

                    if isempty(app.Pp_out_ssl) || isempty(app.Z_ssl) || isempty(app.Spectrum_out_ssl) || isempty(app.Ps_out_ssl) || isempty(app.lambda_s_ssl) || isempty(app.Gain_out_ssl)
                        
                        [Z,lambda_s,Spectrum_out,Pp_out,Ps_out] = TDFRL(app.LengthofFiberLEditField.Value,app.N0EditField.Value,app.rsEditField.Value,app.NAsEditField.Value,app.alphaEditField.Value,app.PumpPowerEditField.Value,app.lambda_pEditField.Value*1e-9);

                        app.Pp_out_ssl = Pp_out;
                        app.Z_ssl = Z;
                        app.Spectrum_out_ssl = Spectrum_out;
                        app.Ps_out_ssl = Ps_out;
                        app.lambda_s_ssl = lambda_s;
                        %app.Gain_out_ssl = Gain_out;
                    end
    
                    if app.graph_X_axis == "Z" && app.graph_Y_axis == "Pump power"
                        app.UIAxes.XLabel.String = "Fiber position (m)";
                        app.UIAxes.YLabel.String = "Pump power (mW)";
                        plot(app.UIAxes,app.Z_ssl,app.Pp_out_ssl);
        
                    elseif app.graph_X_axis == "Z" && app.graph_Y_axis == "Signal power"
                        app.UIAxes.XLabel.String = "Fiber position (m)";
                        app.UIAxes.YLabel.String = "Signal power (mW)";
                        plot(app.UIAxes,app.Z_ssl,app.Ps_out_ssl);
        
                    elseif app.graph_X_axis == "Signal wavelength" && app.graph_Y_axis == "Gain"
                        app.UIAxes.XLabel.String = "Wavelength (m)";
                        app.UIAxes.YLabel.String = 'Gain (dB)';
                        plot(app.UIAxes,app.lambda_s_ssl,app.Gain_out_ssl);
        
                    elseif app.graph_X_axis == "Signal wavelength" && app.graph_Y_axis == "Spectrum"
                        app.UIAxes.XLabel.String = "Wavelength (m)";
                        app.UIAxes.YLabel.String = "Power (dBm)";
                        plot(app.UIAxes,app.lambda_s_ssl,app.Spectrum_out_ssl);
                    end 


                end
               
            elseif app.Type_of_simulation == "FTDT Laser Simulation"

                [Z_out,z_out,Pp_out,Ps_out,Pout_out,t_out,lambda_s_out,inv_out] = FTBS_CW_0(app.N0EditField.Value,app.LengthofFiberLEditField.Value,app.rsEditField.Value,app.NAsEditField.Value,app.lambda_pEditField.Value,app.taufEditField.Value,app.CavityLengthEditField.Value, app.iso_lEditField.Value, app.cout_lEditField.Value, app.aom_lEditField.Value, app.wdm_lEditField.Value, app.ForwardEditField.Value, app.BackwardEditField.Value, app.TEditField.Value, app.qEditField.Value, app.REditField.Value, app.NEditField.Value, app.Fiber_filepath);

                if app.graph_X_axis == "Z" && app.graph_Y_axis == "Pump power"
                    
                    app.UIAxes.XLabel.String = "Fiber position (m)";
                    app.UIAxes.YLabel.String = "Pump power (mW)";
                    plot(app.UIAxes,Z_out,Pp_out);

                elseif app.graph_X_axis == "Z" && app.graph_Y_axis == "Signal power"

                    app.UIAxes.XLabel.String = "Cavity position (m)";
                    app.UIAxes.YLabel.String = "Signal power (mW)";
                    plot(app.UIAxes,z_out,Ps_out);

                elseif app.graph_X_axis == "Z" && app.graph_Y_axis == "Excited State Population"

                    app.UIAxes.XLabel.String = "Fiber position (m)";
                    app.UIAxes.YLabel.String = 'N_1/N_0, N_2/N_0';
                    plot(app.UIAxes,Z_out,inv_out);

                elseif app.graph_X_axis == "time" && app.graph_Y_axis == "total output power"

                    app.UIAxes.XLabel.String = "Time (s)";
                    app.UIAxes.YLabel.String = 'Total Output Power';
                    plot(app.UIAxes,t_out,Pout_out);

                elseif app.graph_X_axis == "Signal wavelength" && app.graph_Y_axis == "Spectrum"

                    app.UIAxes.XLabel.String = "Wavelength (nm)";
                    app.UIAxes.YLabel.String = 'Spectrum';
                    plot(app.UIAxes,lambda_s_out,Pout_out);

                end             


            end
            
            %plot(app.UIAxes,Y);
        end

        % Value changed function: XaxisDropDown
        function XaxisDropDownValueChanged(app, event)
            value = app.XaxisDropDown.Value;
            app.graph_X_axis = value;

            if app.Type_of_simulation == "Steady State Amplifier Simulation"
                if app.graph_X_axis == "Z"
                    app.YaxisDropDown.Items = ["Pump power","Signal power","Excited State Population"];
                    app.graph_Y_axis = "Pump power";
                elseif app.graph_X_axis == "Signal wavelength"
                    app.YaxisDropDown.Items = ["Spectrum"];
                    app.graph_Y_axis = "Spectrum";
                else
                    app.YaxisDropDown.Items = ["no data"];
                end

            elseif app.Type_of_simulation == "Steady State Laser Simulation"
                if app.graph_X_axis == "Z"
                    app.YaxisDropDown.Items = ["Pump power","Signal power"];
                    app.graph_Y_axis = "Pump power";
                elseif app.graph_X_axis == "Signal wavelength"
                    app.YaxisDropDown.Items = ["Spectrum","Gain"];
                    app.graph_Y_axis = "Spectrum";
                else
                    app.YaxisDropDown.Items = ["no data"];
                end
            
            elseif app.Type_of_simulation == "FTDT Laser Simulation"
                if app.graph_X_axis == "Z"
                    app.YaxisDropDown.Items = ["Pump power","Signal power","Excited State Population"];
                    app.graph_Y_axis = "Pump power";
                elseif app.graph_X_axis == "Signal wavelength"
                    app.YaxisDropDown.Items = ["Spectrum"];
                    app.graph_Y_axis = "Spectrum";
                elseif app.graph_X_axis == "time"
                    app.YaxisDropDown.Items = ["total output power"];
                    app.graph_Y_axis = "total output power";
                else
                    app.YaxisDropDown.Items = ["no data"];
                end

            end
        end

        % Value changed function: YaxisDropDown
        function YaxisDropDownValueChanged(app, event)
            value = app.YaxisDropDown.Value;
            app.graph_Y_axis = value;
        end

        % Selection changed function: TypeofCladdingButtonGroup
        function TypeofCladdingButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypeofCladdingButtonGroup.SelectedObject;
            if selectedButton == app.DoubleButton
                app.NApEditField.Enable = 1;
                app.rpEditField.Enable = 1;
                app.Cladding_type = "Double";
             
            elseif selectedButton == app.SingleButton
                app.NApEditField.Enable = 0;
                app.rpEditField.Enable = 0;
                app.Cladding_type = "Single";
            end
        end

        % Selection changed function: GainmediumButtonGroup
        function GainmediumButtonGroupSelectionChanged(app, event)
            selectedButton = app.GainmediumButtonGroup.SelectedObject;
            
            if selectedButton == app.ThuliumDopedFiberButton
                app.Gain_medium = "Thulium";
                app.FilepathEditField.Enable = 0;
                app.FilepathEditField.Value = "";
                app.Fiber_filepath = "D:\Deepa madam's project\Erbium codes\Q-switchedLaser_Codes\EDFAmplifier_Laser_SteadyState\HG980.csv";

                %visiblity and Default values of other fields
                app.LengthofFiberLEditField.Value = 2;
                app.N0EditField.Value = 2.74e26;
                app.NAsEditField.Value = 0.15;
                app.NApEditField.Value = 0.46;
                app.neffEditField.Value = 1.5;
                app.rsEditField.Value = 5e-6;
                app.rpEditField.Value = 65e-6;
                app.taufEditField.Value = 0;
                app.alphaEditField.Value = 0;
                app.lambda_pEditField.Value = 790;


                app.tau21EditField.Enable = 1;
                app.tau21EditField.Value = 334.7;
                app.tau32EditField.Enable = 1;
                app.tau32EditField.Value = 14.2;
                app.K1EditField.Enable = 1;
                app.K1EditField.Value = 1.25e-22;

                
                app.wdm_pEditField.Value = 0;
                app.iso_lEditField.Value = 0;
                app.cout_lEditField.Value = 0;
                app.c_outEditField.Value = 0;
                app.wdm_lEditField.Value = 0;
                app.aom_lEditField.Value = 0;
                app.c_lEditField.Value = 0;
                app.CavityLengthEditField.Value = 0;

                
                app.mWLabel_2.Text = "W";
                app.mWLabel.Text = "W";
                app.PumpPowerEditField.Value = 1900;
                app.SignalPowerEditField.Value = 0;
           
            elseif selectedButton == app.YiterbiumDopedFiberButton
                app.Gain_medium = "Yiterbium";
                app.FilepathEditField.Enable = 0;
                app.FilepathEditField.Value = "";
                app.Fiber_filepath = "D:\Deepa madam's project\Yiterbium codes\YB_spectroscopic data.csv";
                
                %visiblity of other buttons
                app.tau21EditField.Visible = 0;
                app.tau32EditField.Visible = 0;
                app.K1EditField.Visible = 0;

            elseif selectedButton == app.ErbiumDopedFiberButton
                app.Gain_medium = "Erbium";
                app.FilepathEditField.Enable = 0;
                app.FilepathEditField.Value = "";
                app.Fiber_filepath = "D:\Deepa madam's project\Erbium codes\Q-switchedLaser_Codes\EDFAmplifier_Laser_SteadyState\HG980.csv";

                %visiblity and Default values of other fields
                app.LengthofFiberLEditField.Value = 3.5;
                app.N0EditField.Value = 2e25;
                app.NAsEditField.Value = 0.29;
                app.NApEditField.Value = 0;
                app.neffEditField.Value = 1.5;
                app.rsEditField.Value = 2.2e-6;
                app.rpEditField.Value = 0;
                app.taufEditField.Value = 10e-3;
                app.alphaEditField.Value = 0;
                app.lambda_pEditField.Value = 976;


                app.tau21EditField.Enable = 0;
                app.tau21EditField.Value = 0;
                app.tau32EditField.Enable = 0;
                app.tau32EditField.Value = 0;
                app.K1EditField.Enable = 0;
                app.K1EditField.Value = 0;

                
                app.wdm_pEditField.Value = 1.55;
                app.iso_lEditField.Value = 0.9;
                app.cout_lEditField.Value = 0;
                app.c_outEditField.Value = 0;
                app.wdm_lEditField.Value = 0;
                app.aom_lEditField.Value = 0;
                app.c_lEditField.Value = 0;
                app.CavityLengthEditField.Value = 0;

                
                app.mWLabel_2.Text = "mW";
                app.mWLabel.Text = "mW";
                app.PumpPowerEditField.Value = 20;
                app.SignalPowerEditField.Value = 0;

           elseif selectedButton == app.CustomFiberButton
                app.Gain_medium = "Custom";
                app.FilepathEditField.Enable = 1;

                %visiblity of other buttons
                app.tau21EditField.Enable = 0;
                app.tau32EditField.Enable = 0;
                app.K1EditField.Enable = 0;
            end
        end

        % Value changed function: FilepathEditField
        function FilepathEditFieldValueChanged(app, event)
            value = app.FilepathEditField.Value;
            app.Fiber_filepath = value;
        end

        % Selection changed function: TypeButtonGroup
        function TypeButtonGroupSelectionChanged(app, event)
            selectedButton = app.TypeButtonGroup.SelectedObject;
            if selectedButton == app.SteadyStateLaserSimulationButton
                app.Type_of_simulation = "Steady State Laser Simulation";
                
                %FTDT Special
                app.CavityLengthEditField.Visible = 0;
                app.CavityLengthEditFieldLabel.Visible = 0;
                app.mLabel_7.Visible = 0;
                app.TEditField.Enable = 0;
                app.qEditField.Enable = 0;
                app.REditField.Enable = 0;
                app.NEditField.Enable = 0;
                
                app.SignalPowerEditField.Visible = 1;
                app.SignalPowerEditFieldLabel.Visible = 1;
                app.mWLabel.Visible = 1;
                app.PumpPowerEditField.Visible = 1;
                app.PumpPowerEditFieldLabel.Visible = 1;
                app.mWLabel_2.Visible = 1;
                app.PumpPowerLabel.Visible = 0;
                app.ForwardEditField.Visible = 0;
                app.ForwardEditField.Value = 0;
                app.ForwardEditFieldLabel.Visible = 0;
                app.BackwardEditField.Visible = 0;
                app.BackwardEditField.Value = 0;
                app.BackwardEditFieldLabel.Visible = 0;
                app.mWLabel_3.Visible = 0;
                app.mWLabel_4.Visible = 0;


                app.c_lEditField.Enable = 1;
                app.aom_lEditField.Enable = 1;
                app.wdm_lEditField.Enable = 1;
                app.cout_lEditField.Enable = 1;
                app.c_outEditField.Enable = 1;
                app.SignalPowerEditField.Enable = 0;
                app.YaxisDropDown.Items = ["Pump power","Signal power"];
                app.graph_Y_axis = "Pump power";
                app.graph_X_axis = "Z";
                app.XaxisDropDown.Value = "Z";
                app.YaxisDropDown.Value = "Pump power";

                %Default Values 
                app.LengthofFiberLEditField.Value = 3.5;
                app.N0EditField.Value = 2e25;
                app.neffEditField.Value = 1.5;
                app.alphaEditField.Value = 0;
                app.NAsEditField.Value = 0.29;
                app.NApEditField.Value = 0;
                app.rsEditField.Value = 2.2e-6;
                app.rpEditField.Value = 0;
                app.taufEditField.Value = 10e-3;
                app.wdm_pEditField.Value = 1.55;
                app.iso_lEditField.Value = 0.9;
                app.cout_lEditField.Value = 3.35;
                app.c_outEditField.Value = 3.35;
                app.wdm_lEditField.Value = 1.1;
                app.aom_lEditField.Value = 0;
                app.c_lEditField.Value = 0;
                app.lambda_pEditField.Value = 976;
                app.lambda_s_startEditField.Value = 1501e-9;
                app.lambda_s_endEditField.Value = 1600e-9;
                app.lambda_s_stepEditField.Value = 0.1e-9;
                app.PumpPowerEditField.Value = 20;


            elseif selectedButton == app.FDTDLaserSimulationButton
                app.Type_of_simulation = "FTDT Laser Simulation";
                app.CavityLengthEditField.Visible = 1;
                app.CavityLengthEditFieldLabel.Visible = 1;
                app.mLabel_7.Visible = 1;

                app.TEditField.Enable = 1;
                app.qEditField.Enable = 1;
                app.REditField.Enable = 1;
                app.NEditField.Enable = 1;
                
                app.PumpPowerEditField.Visible = 0;
                app.PumpPowerEditFieldLabel.Visible = 0;
                app.mWLabel.Visible = 0;
                app.SignalPowerEditField.Visible = 0;
                app.SignalPowerEditFieldLabel.Visible = 0;
                app.mWLabel_2.Visible = 0;
                app.PumpPowerLabel.Visible = 1;
                app.ForwardEditField.Visible = 1;
                app.ForwardEditFieldLabel.Visible = 1;
                app.BackwardEditField.Visible = 1;
                app.BackwardEditFieldLabel.Visible = 1;
                app.mWLabel_3.Visible = 1;
                app.mWLabel_4.Visible = 1;

                app.c_lEditField.Enable = 1;
                app.aom_lEditField.Enable = 1;
                app.wdm_lEditField.Enable = 1;
                app.cout_lEditField.Enable = 1;
                app.c_outEditField.Enable = 1;

                app.YaxisDropDown.Items = ["Pump power","Signal power","Excited State Population"];
                app.XaxisDropDown.Items = ["Z","Signal Wavelength","time"];
                app.graph_Y_axis = "Pump power";
                app.graph_X_axis = "Z";
                app.XaxisDropDown.Value = "Z";
                app.YaxisDropDown.Value = "Pump power";

                %Default Values 
                app.LengthofFiberLEditField.Value = 3.5;
                app.N0EditField.Value = 2e25;
                app.neffEditField.Value = 1.5;
                app.alphaEditField.Value = 0;
                app.NAsEditField.Value = 0.29;
                app.NApEditField.Value = 0;
                app.rsEditField.Value = 2.2e-6;
                app.rpEditField.Value = 0;
                app.taufEditField.Value = 10e-3;
                app.wdm_pEditField.Value = 1.55;
                app.iso_lEditField.Value = 0.9;
                app.cout_lEditField.Value = 3.35;
                app.c_outEditField.Value = 3.35;
                app.wdm_lEditField.Value = 1.1;
                app.aom_lEditField.Value = 0;
                app.c_lEditField.Value = 0;
                app.CavityLengthEditField.Value = 10;
                app.lambda_pEditField.Value = 976;
                app.lambda_s_startEditField.Value = 1501e-9;
                app.lambda_s_endEditField.Value = 1600e-9;
                app.lambda_s_stepEditField.Value = 0.1e-9;
                %app.PumpPowerEditField.Value = 20;
                app.ForwardEditField.Value = 20;
                app.BackwardEditField.Value = 0;
                app.TEditField.Value = 1e-4;
                app.qEditField.Value = 15;
                app.REditField.Value = 0.9;
                app.NEditField.Value = 50;

            elseif selectedButton == app.PulsedLaserSimulationButton
                app.Type_of_simulation = "Pulsed Laser Simulation";

                %FTDT
                app.CavityLengthEditField.Visible = 0;
                app.CavityLengthEditFieldLabel.Visible = 0;
                app.mLabel_7.Visible = 0;
                
                app.SignalPowerEditField.Visible = 1;
                app.SignalPowerEditFieldLabel.Visible = 1;
                app.mWLabel.Visible = 1;
                app.PumpPowerEditField.Visible = 1;
                app.PumpPowerEditFieldLabel.Visible = 1;
                app.mWLabel_2.Visible = 1;
                app.PumpPowerLabel.Visible = 0;
                app.ForwardEditField.Visible = 0;
                app.ForwardEditField.Value = 0;
                app.ForwardEditFieldLabel.Visible = 0;
                app.BackwardEditField.Visible = 0;
                app.BackwardEditField.Value = 0;
                app.BackwardEditFieldLabel.Visible = 0;
                app.mWLabel_3.Visible = 0;
                app.mWLabel_4.Visible = 0;

            elseif selectedButton == app.SteadyStateAmplifierSimulationButton
                app.Type_of_simulation = "Steady State Amplifier Simulation";

                %FTDT
                app.CavityLengthEditField.Visible = 0;
                app.CavityLengthEditFieldLabel.Visible = 0;
                app.mLabel_7.Visible = 0;
                app.TEditField.Enable = 0;
                app.qEditField.Enable = 0;
                app.REditField.Enable = 0;
                app.NEditField.Enable = 0;
                
                app.SignalPowerEditField.Visible = 1;
                app.SignalPowerEditFieldLabel.Visible = 1;
                app.mWLabel.Visible = 1;
                app.PumpPowerEditField.Visible = 1;
                app.PumpPowerEditFieldLabel.Visible = 1;
                app.mWLabel_2.Visible = 1;
                app.PumpPowerLabel.Visible = 0;
                app.ForwardEditField.Visible = 0;
                app.ForwardEditField.Value = 0;
                app.ForwardEditFieldLabel.Visible = 0;
                app.BackwardEditField.Visible = 0;
                app.BackwardEditField.Value = 0;
                app.BackwardEditFieldLabel.Visible = 0;
                app.mWLabel_3.Visible = 0;
                app.mWLabel_4.Visible = 0;


                app.c_lEditField.Enable = 0;
                app.aom_lEditField.Enable = 0;
                app.wdm_lEditField.Enable = 0;
                app.cout_lEditField.Enable = 0;
                app.c_outEditField.Enable = 0;
                app.SignalPowerEditField.Enable = 1;
                app.YaxisDropDown.Items = ["Pump power","Signal power","Excited State Population"];
                app.graph_Y_axis = "Pump power";
                app.graph_X_axis = "Z";
                app.XaxisDropDown.Value = "Z";
                app.YaxisDropDown.Value = "Pump power";

                %Default Values 
                app.LengthofFiberLEditField.Value = 3.5;
                app.N0EditField.Value = 2e25;
                app.neffEditField.Value = 1.5;
                app.alphaEditField.Value = 0;
                app.NAsEditField.Value = 0.29;
                app.NApEditField.Value = 0;
                app.rsEditField.Value = 2.2e-6;
                app.rpEditField.Value = 0;
                app.taufEditField.Value = 10e-3;
                app.wdm_pEditField.Value = 1.55;
                app.iso_lEditField.Value = 0.9;
                app.cout_lEditField.Value = 0;
                app.c_outEditField.Value = 0;
                app.wdm_lEditField.Value = 0;
                app.aom_lEditField.Value = 0;
                app.c_lEditField.Value = 0;
                app.lambda_pEditField.Value = 976;
                app.lambda_s_startEditField.Value = 1501e-9;
                app.lambda_s_endEditField.Value = 1600e-9;
                app.lambda_s_stepEditField.Value = 0.1e-9;
                app.PumpPowerEditField.Value = 20;

            end
        end

        % Button pushed function: RefreshButton
        function RefreshButtonPushed(app, event)
            app.Z_ssl = [];          
            app.lambda_s_ssl = [];
            app.Spectrum_out_ssl = [];
            app.Gain_out_ssl = [];
            app.Pp_out_ssl = [];
            app.Ps_out_ssl = [];
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab1 = uitab(app.TabGroup);
            app.Tab1.Title = 'Tab1';
            app.TypeButtonGroup = uibuttongroup(app.Tab1);
            app.TypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeButtonGroupSelectionChanged, true);
            app.TypeButtonGroup.Title = 'Type :';
            app.SteadyStateAmplifierSimulationButton = uiradiobutton(app.TypeButtonGroup);
            app.SteadyStateAmplifierSimulationButton.Text = 'Steady State  Amplifier Simulation';
            app.SteadyStateAmplifierSimulationButton.Value = true;
            app.SteadyStateLaserSimulationButton = uiradiobutton(app.TypeButtonGroup);
            app.SteadyStateLaserSimulationButton.Text = 'Steady State  Laser Simulation';
            app.FDTDLaserSimulationButton = uiradiobutton(app.TypeButtonGroup);
            app.FDTDLaserSimulationButton.Text = 'FDTD Laser Simulation';
            app.PulsedLaserSimulationButton = uiradiobutton(app.TypeButtonGroup);
            app.PulsedLaserSimulationButton.Text = 'Pulsed Laser Simulation';
            app.GainmediumButtonGroup = uibuttongroup(app.Tab1);
            app.GainmediumButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @GainmediumButtonGroupSelectionChanged, true);
            app.GainmediumButtonGroup.Title = 'Gain medium :';
            app.ErbiumDopedFiberButton = uiradiobutton(app.GainmediumButtonGroup);
            app.ErbiumDopedFiberButton.Text = 'Erbium Doped Fiber';
            app.YiterbiumDopedFiberButton = uiradiobutton(app.GainmediumButtonGroup);
            app.YiterbiumDopedFiberButton.Text = 'Yiterbium Doped Fiber';
            app.ThuliumDopedFiberButton = uiradiobutton(app.GainmediumButtonGroup);
            app.ThuliumDopedFiberButton.Text = 'Thulium Doped Fiber ';
            app.ThuliumDopedFiberButton.Value = true;
            app.CustomFiberButton = uiradiobutton(app.GainmediumButtonGroup);
            app.CustomFiberButton.Text = 'Custom Fiber';
            app.FilepathEditFieldLabel = uilabel(app.GainmediumButtonGroup);
            app.FilepathEditFieldLabel.Text = 'File path :';
            app.FilepathEditField = uieditfield(app.GainmediumButtonGroup, 'text');
            app.FilepathEditField.ValueChangedFcn = createCallbackFcn(app, @FilepathEditFieldValueChanged, true);
            app.Tab2 = uitab(app.TabGroup);
            app.Tab2.Title = 'Tab2';
            app.FiberParametersLabel = uilabel(app.Tab2);
            app.FiberParametersLabel.Text = 'Fiber Parameters :';
            app.LengthofFiberLLabel = uilabel(app.Tab2);
            app.LengthofFiberLLabel.Text = 'Length of Fiber (L) =';
            app.LengthofFiberLEditField = uieditfield(app.Tab2, 'numeric');
            app.Label = uilabel(app.Tab2);
            app.Label.Interpreter = 'html';
            app.Label.Text = 'Total Dopant Concentration (N<sub>0</sub>) =';
            app.N0EditField = uieditfield(app.Tab2, 'numeric');
            app.nsubeffsubLabel = uilabel(app.Tab2);
            app.nsubeffsubLabel.Interpreter = 'html';
            app.nsubeffsubLabel.Text = 'Fiber Refractive Index (n<sub>eff</sub>) =';
            app.neffEditField = uieditfield(app.Tab2, 'numeric');
            app.alphaLabel = uilabel(app.Tab2);
            app.alphaLabel.Interpreter = 'html';
            app.alphaLabel.Text = '<p>Fiber Loss (&#945) =</p>';
            app.alphaEditField = uieditfield(app.Tab2, 'numeric');
            app.TypeofCladdingButtonGroup = uibuttongroup(app.Tab2);
            app.TypeofCladdingButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @TypeofCladdingButtonGroupSelectionChanged, true);
            app.TypeofCladdingButtonGroup.Title = 'Type of Cladding :';
            app.SingleButton = uiradiobutton(app.TypeofCladdingButtonGroup);
            app.SingleButton.Text = 'Single';
            app.SingleButton.Value = true;
            app.DoubleButton = uiradiobutton(app.TypeofCladdingButtonGroup);
            app.DoubleButton.Text = 'Double ';
            app.NA_sLabel = uilabel(app.Tab2);
            app.NA_sLabel.Interpreter = 'html';
            app.NA_sLabel.Text = 'Numerical Apperture of Core (NA<sub>s</sub>) =';
            app.NAsEditField = uieditfield(app.Tab2, 'numeric');
            app.NumericalAppertureofCladdingNAsubpsubLabel = uilabel(app.Tab2);
            app.NumericalAppertureofCladdingNAsubpsubLabel.Interpreter = 'html';
            app.NumericalAppertureofCladdingNAsubpsubLabel.Text = 'Numerical Apperture of Cladding (NA<sub>p</sub>) =';
            app.NApEditField = uieditfield(app.Tab2, 'numeric');
            app.RadiusofCorersubssubLabel = uilabel(app.Tab2);
            app.RadiusofCorersubssubLabel.Interpreter = 'html';
            app.RadiusofCorersubssubLabel.Text = 'Radius of Core (r<sub>s</sub>) =';
            app.rsEditField = uieditfield(app.Tab2, 'numeric');
            app.RadiusofCladdingrsubpsubLabel = uilabel(app.Tab2);
            app.RadiusofCladdingrsubpsubLabel.Interpreter = 'html';
            app.RadiusofCladdingrsubpsubLabel.Text = 'Radius of Cladding (r<sub>p</sub>) =';
            app.rpEditField = uieditfield(app.Tab2, 'numeric');
            app.LifetimeofMetastableStatetau_fLabel = uilabel(app.Tab2);
            app.LifetimeofMetastableStatetau_fLabel.Interpreter = 'html';
            app.LifetimeofMetastableStatetau_fLabel.Text = '<p>Lifetime of Metastable State (	&#932<sub>f</sub>) =</p>';
            app.taufEditField = uieditfield(app.Tab2, 'numeric');
            app.mLabel = uilabel(app.Tab2);
            app.mLabel.Text = 'm';
            app.DbLabel = uilabel(app.Tab2);
            app.DbLabel.Text = 'Db';
            app.mLabel_2 = uilabel(app.Tab2);
            app.mLabel_2.Text = 'm';
            app.mLabel_3 = uilabel(app.Tab2);
            app.mLabel_3.Text = 'm';
            app.sLabel = uilabel(app.Tab2);
            app.sLabel.Text = 's';
            app.m3Label = uilabel(app.Tab2);
            app.m3Label.Interpreter = 'latex';
            app.m3Label.Text = '$m^{-3}$';
            app.Tab7 = uitab(app.TabGroup);
            app.Tab7.Title = 'Tab7';
            app.MoreFiberParametersLabel = uilabel(app.Tab7);
            app.MoreFiberParametersLabel.Text = 'More Fiber Parameters : ';
            app.tau21Label = uilabel(app.Tab7);
            app.tau21Label.Interpreter = 'html';
            app.tau21Label.Text = '<p>Lifetime of level 2 (&#932<sub>21</sub>) =</p>';
            app.tau21EditField = uieditfield(app.Tab7, 'numeric');
            app.p956mpLabel = uilabel(app.Tab7);
            app.p956mpLabel.Interpreter = 'html';
            app.p956mpLabel.Text = '<p>&#956m</p>';
            app.tau32Label = uilabel(app.Tab7);
            app.tau32Label.Interpreter = 'html';
            app.tau32Label.Text = '<p>Lifetime of level 3 (&#932<sub>32</sub>) =</p>';
            app.tau32EditField = uieditfield(app.Tab7, 'numeric');
            app.p956mpLabel_2 = uilabel(app.Tab7);
            app.p956mpLabel_2.Interpreter = 'html';
            app.p956mpLabel_2.Text = '<p>&#956m</p>';
            app.CrossRelaxationCoefficientLabel = uilabel(app.Tab7);
            app.CrossRelaxationCoefficientLabel.Text = 'Cross Relaxation Coefficient  =';
            app.K1EditField = uieditfield(app.Tab7, 'numeric');
            app.Tab3 = uitab(app.TabGroup);
            app.Tab3.Title = 'Tab3';
            app.CavityParametersLabel = uilabel(app.Tab3);
            app.CavityParametersLabel.Text = 'Cavity Parameters :';
            app.LossesLabel = uilabel(app.Tab3);
            app.LossesLabel.Text = 'Losses :';
            app.insertionlossofisolatorEditFieldLabel = uilabel(app.Tab3);
            app.insertionlossofisolatorEditFieldLabel.Text = 'insertion loss of isolator =';
            app.iso_lEditField = uieditfield(app.Tab3, 'numeric');
            app.insertionlossofwdmatpumpLabel = uilabel(app.Tab3);
            app.insertionlossofwdmatpumpLabel.Text = 'insertion loss of wdm at pump =';
            app.wdm_pEditField = uieditfield(app.Tab3, 'numeric');
            app.insertionlossofcoupleratfeedbackportEditFieldLabel = uilabel(app.Tab3);
            app.insertionlossofcoupleratfeedbackportEditFieldLabel.Text = 'insertion loss of coupler at feedback port =';
            app.cout_lEditField = uieditfield(app.Tab3, 'numeric');
            app.insertionlossofcoupleratoutputportEditFieldLabel = uilabel(app.Tab3);
            app.insertionlossofcoupleratoutputportEditFieldLabel.Text = 'insertion loss of coupler at output port =';
            app.c_outEditField = uieditfield(app.Tab3, 'numeric');
            app.insertionlossofwdmEditFieldLabel = uilabel(app.Tab3);
            app.insertionlossofwdmEditFieldLabel.Text = 'insertion loss of wdm =';
            app.wdm_lEditField = uieditfield(app.Tab3, 'numeric');
            app.insertionlossofaomLabel = uilabel(app.Tab3);
            app.insertionlossofaomLabel.Text = 'insertion loss of aom =';
            app.aom_lEditField = uieditfield(app.Tab3, 'numeric');
            app.additionalconnectorlossEditFieldLabel = uilabel(app.Tab3);
            app.additionalconnectorlossEditFieldLabel.Text = 'additional connector loss =';
            app.c_lEditField = uieditfield(app.Tab3, 'numeric');
            app.dBLabel = uilabel(app.Tab3);
            app.dBLabel.Text = 'dB';
            app.dBLabel_2 = uilabel(app.Tab3);
            app.dBLabel_2.Text = 'dB';
            app.dBLabel_3 = uilabel(app.Tab3);
            app.dBLabel_3.Text = 'dB';
            app.dBLabel_4 = uilabel(app.Tab3);
            app.dBLabel_4.Text = 'dB';
            app.dBLabel_5 = uilabel(app.Tab3);
            app.dBLabel_5.Text = 'dB';
            app.dBLabel_6 = uilabel(app.Tab3);
            app.dBLabel_6.Text = 'dB';
            app.dBLabel_7 = uilabel(app.Tab3);
            app.dBLabel_7.Text = 'dB';
            app.CavityLengthEditFieldLabel = uilabel(app.Tab3);
            app.CavityLengthEditFieldLabel.Text = 'Cavity Length =';
            app.CavityLengthEditField = uieditfield(app.Tab3, 'numeric');
            app.mLabel_7 = uilabel(app.Tab3);
            app.mLabel_7.Text = 'm';
            app.Tab4 = uitab(app.TabGroup);
            app.Tab4.Title = 'Tab4';
            app.InputsLabel = uilabel(app.Tab4);
            app.InputsLabel.Text = 'Inputs :';
            app.lambda_pLabel = uilabel(app.Tab4);
            app.lambda_pLabel.Interpreter = 'latex';
            app.lambda_pLabel.Text = 'Pump Wavelength ($\lambda_{p}$) =';
            app.lambda_pEditField = uieditfield(app.Tab4, 'numeric');
            app.lambda_sstartLabel = uilabel(app.Tab4);
            app.lambda_sstartLabel.Interpreter = 'latex';
            app.lambda_sstartLabel.Text = 'Signal Wavelength ($\lambda_{s}$) start =';
            app.lambda_s_startEditField = uieditfield(app.Tab4, 'numeric');
            app.lambda_sendLabel = uilabel(app.Tab4);
            app.lambda_sendLabel.Interpreter = 'latex';
            app.lambda_sendLabel.Text = ' Signal Wavelength ($\lambda_{s}$) end =';
            app.lambda_s_endEditField = uieditfield(app.Tab4, 'numeric');
            app.SignalWavelengthlambda_sstepLabel = uilabel(app.Tab4);
            app.SignalWavelengthlambda_sstepLabel.Interpreter = 'latex';
            app.SignalWavelengthlambda_sstepLabel.Text = 'Signal Wavelength ($\lambda_{s}$) step =';
            app.lambda_s_stepEditField = uieditfield(app.Tab4, 'numeric');
            app.PumpPowerEditFieldLabel = uilabel(app.Tab4);
            app.PumpPowerEditFieldLabel.Text = 'Pump Power =';
            app.PumpPowerEditField = uieditfield(app.Tab4, 'numeric');
            app.SignalPowerEditFieldLabel = uilabel(app.Tab4);
            app.SignalPowerEditFieldLabel.Text = 'Signal Power =';
            app.SignalPowerEditField = uieditfield(app.Tab4, 'numeric');
            app.mWLabel = uilabel(app.Tab4);
            app.mWLabel.Text = 'mW';
            app.mWLabel_2 = uilabel(app.Tab4);
            app.mWLabel_2.Text = 'mW';
            app.mLabel_4 = uilabel(app.Tab4);
            app.mLabel_4.Text = 'm';
            app.mLabel_5 = uilabel(app.Tab4);
            app.mLabel_5.Text = 'm';
            app.mLabel_6 = uilabel(app.Tab4);
            app.mLabel_6.Text = 'm';
            app.nmLabel_4 = uilabel(app.Tab4);
            app.nmLabel_4.Text = 'nm';
            app.PumpPowerLabel = uilabel(app.Tab4);
            app.PumpPowerLabel.Text = 'Pump Power :';
            app.ForwardEditFieldLabel = uilabel(app.Tab4);
            app.ForwardEditFieldLabel.Text = 'Forward =';
            app.ForwardEditField = uieditfield(app.Tab4, 'numeric');
            app.BackwardEditFieldLabel = uilabel(app.Tab4);
            app.BackwardEditFieldLabel.Text = 'Backward =';
            app.BackwardEditField = uieditfield(app.Tab4, 'numeric');
            app.mWLabel_3 = uilabel(app.Tab4);
            app.mWLabel_3.Text = 'mW';
            app.mWLabel_4 = uilabel(app.Tab4);
            app.mWLabel_4.Text = 'mW';
            app.Tab6 = uitab(app.TabGroup);
            app.Tab6.Title = 'Tab6';
            app.FDTDSimulationParametersLabel = uilabel(app.Tab6);
            app.FDTDSimulationParametersLabel.Text = 'FDTD Simulation Parameters :';
            app.CycleTimeLabel = uilabel(app.Tab6);
            app.CycleTimeLabel.Text = 'Cycle Time =';
            app.TEditField = uieditfield(app.Tab6, 'numeric');
            app.sLabel_2 = uilabel(app.Tab6);
            app.sLabel_2.Text = 's';
            app.NumberofCyclesEditFieldLabel = uilabel(app.Tab6);
            app.NumberofCyclesEditFieldLabel.Text = 'Number of Cycles =';
            app.qEditField = uieditfield(app.Tab6, 'numeric');
            app.StabilityFactorEditFieldLabel = uilabel(app.Tab6);
            app.StabilityFactorEditFieldLabel.Text = 'Stability Factor =';
            app.REditField = uieditfield(app.Tab6, 'numeric');
            app.pNumberofstepsbroverthegainmediumpLabel = uilabel(app.Tab6);
            app.pNumberofstepsbroverthegainmediumpLabel.Interpreter = 'html';
            app.pNumberofstepsbroverthegainmediumpLabel.Text = '<p>Number of steps =<br> over gain medium   </p>';
            app.NEditField = uieditfield(app.Tab6, 'numeric');
            app.Tab5 = uitab(app.TabGroup);
            app.Tab5.Title = 'Tab5';
            app.UIAxes = uiaxes(app.Tab5);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.OutputsLabel = uilabel(app.Tab5);
            app.OutputsLabel.Text = 'Outputs :';
            app.XaxisDropDownLabel = uilabel(app.Tab5);
            app.XaxisDropDownLabel.Text = 'X-axis :';
            app.XaxisDropDown = uidropdown(app.Tab5);
            app.XaxisDropDown.Items = {'Z', 'lambda_s'};
            app.XaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @XaxisDropDownValueChanged, true);
            app.XaxisDropDown.Value = 'Z';
            app.YaxisDropDownLabel = uilabel(app.Tab5);
            app.YaxisDropDownLabel.Text = 'Y-axis :';
            app.YaxisDropDown = uidropdown(app.Tab5);
            app.YaxisDropDown.Items = {'Pump power', 'Signal power', 'Spectrum'};
            app.YaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @YaxisDropDownValueChanged, true);
            app.YaxisDropDown.Value = 'Pump power';
            app.PlotButton = uibutton(app.Tab5, 'push');
            app.PlotButton.ButtonPushedFcn = createCallbackFcn(app, @PlotButtonPushed, true);
            app.PlotButton.Text = 'Plot';
            app.RefreshButton = uibutton(app.Tab5, 'push');
            app.RefreshButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshButtonPushed, true);
            app.RefreshButton.Text = 'Refresh';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = project_app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end